classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EXPORTDATABEFOREMVCButton  matlab.ui.control.Button
        TrackA_FC1                 matlab.ui.control.NumericEditField
        TrackA_FC2                 matlab.ui.control.NumericEditField
        FilterOrderDropdown        matlab.ui.control.DropDown
        FilterTypeDropdown         matlab.ui.control.DropDown
        EXPORTDATAAFTERMVCButton   matlab.ui.control.Button
        MVCNORMALISATIONButton     matlab.ui.control.Button
        PROCESSINGTRACKBButton     matlab.ui.control.Button
        PROCESSINGTRACKAButton     matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        RAWDATATab                 matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        CH1Tab                     matlab.ui.container.Tab
        RawDataChannel1            matlab.ui.control.UIAxes
        CH2Tab                     matlab.ui.container.Tab
        RawDataChannel2            matlab.ui.control.UIAxes
        CH3Tab                     matlab.ui.container.Tab
        RawDataChannel3            matlab.ui.control.UIAxes
        CH4Tab                     matlab.ui.container.Tab
        RawDataChannel4            matlab.ui.control.UIAxes
        CH5Tab                     matlab.ui.container.Tab
        RawDataChannel5            matlab.ui.control.UIAxes
        mVDATATab                  matlab.ui.container.Tab
        TabGroup2_2                matlab.ui.container.TabGroup
        CH1Tab_2                   matlab.ui.container.Tab
        MvDataChannel1             matlab.ui.control.UIAxes
        CH2Tab_2                   matlab.ui.container.Tab
        MvDataChannel2             matlab.ui.control.UIAxes
        CH3Tab_2                   matlab.ui.container.Tab
        MvDataChannel3             matlab.ui.control.UIAxes
        CH4Tab_2                   matlab.ui.container.Tab
        MvDataChannel4             matlab.ui.control.UIAxes
        CH5Tab_2                   matlab.ui.container.Tab
        MvDataChannel5             matlab.ui.control.UIAxes
        FFTANALYSISTab             matlab.ui.container.Tab
        TabGroup2_3                matlab.ui.container.TabGroup
        CH1Tab_3                   matlab.ui.container.Tab
        FFTDataChannel1            matlab.ui.control.UIAxes
        CH2Tab_3                   matlab.ui.container.Tab
        FFTDataChannel2            matlab.ui.control.UIAxes
        CH3Tab_3                   matlab.ui.container.Tab
        FFTDataChannel3            matlab.ui.control.UIAxes
        CH4Tab_3                   matlab.ui.container.Tab
        FFTDataChannel4            matlab.ui.control.UIAxes
        CH5Tab_3                   matlab.ui.container.Tab
        FFTDataChannel5            matlab.ui.control.UIAxes
        REMOVEOUTLIERTab           matlab.ui.container.Tab
        TabGroup2_4                matlab.ui.container.TabGroup
        CH1Tab_4                   matlab.ui.container.Tab
        OutlierDataChannel1        matlab.ui.control.UIAxes
        CH2Tab_4                   matlab.ui.container.Tab
        OutlierDataChannel2        matlab.ui.control.UIAxes
        CH3Tab_4                   matlab.ui.container.Tab
        OutlierDataChannel3        matlab.ui.control.UIAxes
        CH4Tab_4                   matlab.ui.container.Tab
        OutlierDataChannel4        matlab.ui.control.UIAxes
        CH5Tab_4                   matlab.ui.container.Tab
        OutlierDataChannel5        matlab.ui.control.UIAxes
        PROCESSINGTRACKATab        matlab.ui.container.Tab
        TabGroup2_5                matlab.ui.container.TabGroup
        CH1Tab_5                   matlab.ui.container.Tab
        TrackAchannel1             matlab.ui.control.UIAxes
        CH2Tab_5                   matlab.ui.container.Tab
        TrackAchannel2             matlab.ui.control.UIAxes
        CH3Tab_5                   matlab.ui.container.Tab
        TrackAchannel3             matlab.ui.control.UIAxes
        CH4Tab_5                   matlab.ui.container.Tab
        TrackAchannel4             matlab.ui.control.UIAxes
        CH5Tab_5                   matlab.ui.container.Tab
        TrackAchannel5             matlab.ui.control.UIAxes
        PROCESSINGTRACKBTab        matlab.ui.container.Tab
        MVCNORMALISATIONTab        matlab.ui.container.Tab
        OutlierOptionDropDown      matlab.ui.control.DropDown
        REMOVEOUTLIERButton        matlab.ui.control.Button
        FFTButton                  matlab.ui.control.Button
        TOmVButton                 matlab.ui.control.Button
        mVDATALabel                matlab.ui.control.Label
        TEXTFILEDATALabel          matlab.ui.control.Label
        FilenameEditField          matlab.ui.control.EditField
        FilenameEditFieldLabel     matlab.ui.control.Label
        UITable_2                  matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
        READTXTFILEButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mVDataMatrix; % Description
        nSeq; % Description
        FFTDataMatrix % Description
        dropDownVal % Description
        rawChannelData % Description
        FilterType = 'hp' % Description
        FilterOrder = 1; % Description
        SettingsUsed = ["Here is a list of all the settings used:"]; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READTXTFILEButton
        function READTXTFILEButtonPushed(app, event)

[filename,path]=uigetfile("DATA/PP01/*.txt");
app.FilenameEditField.Value = filename;
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
loadedData = readmatrix(pathToFile);
%vars = {'nSeq','DI','CH1','CH2','CH3','CH4','CH5'};
%app.UITable.ColumnName = vars;
app.UITable.Data = loadedData;
fclose(fileID);
figure(app.UIFigure);

%STAP 2
app.rawChannelData = loadedData(:,3:end);
app.nSeq = loadedData(:,1);

channel1RawData = app.rawChannelData(:,1);
channel2RawData = app.rawChannelData(:,2);
channel3RawData = app.rawChannelData(:,3);
channel4RawData = app.rawChannelData(:,4);
channel5RawData = app.rawChannelData(:,5);

plot(app.RawDataChannel1,app.nSeq,channel1RawData);
plot(app.RawDataChannel2,app.nSeq,channel2RawData);
plot(app.RawDataChannel3,app.nSeq,channel3RawData);
plot(app.RawDataChannel4,app.nSeq,channel4RawData);
plot(app.RawDataChannel5,app.nSeq,channel5RawData);

if (height(app.SettingsUsed) == 1)
    app.SettingsUsed = [app.SettingsUsed; ("Import: " + pathToFile)];
end

app.TOmVButton.Enable = 'on';
app.mVDataMatrix = ((app.rawChannelData ./ (2^16)) - (0.5)) * 3;


app.FFTDataMatrix = fft(app.mVDataMatrix);


        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;

        end

        % Button pushed function: TOmVButton
        function TOmVButtonPushed(app, event)
                
            app.UITable_2.Data = app.mVDataMatrix;

            channel1MvData = app.mVDataMatrix(:,1);
            channel2MvData = app.mVDataMatrix(:,2);
            channel3MvData = app.mVDataMatrix(:,3);
            channel4MvData = app.mVDataMatrix(:,4);
            channel5MvData = app.mVDataMatrix(:,5);

            plot(app.MvDataChannel1,app.nSeq,channel1MvData);
            plot(app.MvDataChannel2,app.nSeq,channel2MvData);
            plot(app.MvDataChannel3,app.nSeq,channel3MvData);
            plot(app.MvDataChannel4,app.nSeq,channel4MvData);
            plot(app.MvDataChannel5,app.nSeq,channel5MvData);

            if (height(app.SettingsUsed) == 2)
                app.SettingsUsed = [app.SettingsUsed; "Convert to mV"];
            end
            
            app.FFTButton.Enable = 'on';

        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            channel1FFTData = app.FFTDataMatrix(:,1);
            channel2FFTData = app.FFTDataMatrix(:,2);
            channel3FFTData = app.FFTDataMatrix(:,3);
            channel4FFTData = app.FFTDataMatrix(:,4);
            channel5FFTData = app.FFTDataMatrix(:,5);


            n = ceil(numel(app.nSeq)/2);
            halfNSEQ = app.nSeq(n+1:end);

            n1 = ceil(numel(channel1FFTData)/2);
            halfCH1 = channel1FFTData(n1+1:end);

            n2 = ceil(numel(channel2FFTData)/2);
            halfCH2 = channel2FFTData(n2+1:end);

            n3 = ceil(numel(channel3FFTData)/2);
            halfCH3 = channel3FFTData(n3+1:end);

            n4 = ceil(numel(channel4FFTData)/2);
            halfCH4 = channel4FFTData(n4+1:end);

            n5 = ceil(numel(channel5FFTData)/2);
            halfCH5 = channel5FFTData(n5+1:end);

            plot(app.FFTDataChannel1,halfNSEQ,halfCH1);
            plot(app.FFTDataChannel2,halfNSEQ,halfCH2);
            plot(app.FFTDataChannel3,halfNSEQ,halfCH3);
            plot(app.FFTDataChannel4,halfNSEQ,halfCH4);
            plot(app.FFTDataChannel5,halfNSEQ,halfCH5);

            if (height(app.SettingsUsed) == 3)
                app.SettingsUsed = [app.SettingsUsed; "Executed Fast Fourrier Transform"];
            end
            
            app.OutlierOptionDropDown.Enable = 'on';
            app.REMOVEOUTLIERButton.Enable = 'on';

        end

        % Button pushed function: REMOVEOUTLIERButton
        function REMOVEOUTLIERButtonPushed(app, event)

            Outlier1Channel1 = rmoutliers(app.mVDataMatrix(:,1),"mean","ThresholdFactor",1);
            Outlier1Channel2 = rmoutliers(app.mVDataMatrix(:,2),"mean","ThresholdFactor",1);
            Outlier1Channel3 = rmoutliers(app.mVDataMatrix(:,3),"mean","ThresholdFactor",1);
            Outlier1Channel4 = rmoutliers(app.mVDataMatrix(:,4),"mean","ThresholdFactor",1);
            Outlier1Channel5 = rmoutliers(app.mVDataMatrix(:,5),"mean","ThresholdFactor",1);

            Outlier2Channel1 = rmoutliers(app.mVDataMatrix(:,1),"mean","ThresholdFactor",2);
            Outlier2Channel2 = rmoutliers(app.mVDataMatrix(:,2),"mean","ThresholdFactor",2);
            Outlier2Channel3 = rmoutliers(app.mVDataMatrix(:,3),"mean","ThresholdFactor",2);
            Outlier2Channel4 = rmoutliers(app.mVDataMatrix(:,4),"mean","ThresholdFactor",2);
            Outlier2Channel5 = rmoutliers(app.mVDataMatrix(:,5),"mean","ThresholdFactor",2);

            Outlier3Channel1 = rmoutliers(app.mVDataMatrix(:,1),"mean","ThresholdFactor",3);
            Outlier3Channel2 = rmoutliers(app.mVDataMatrix(:,2),"mean","ThresholdFactor",3);
            Outlier3Channel3 = rmoutliers(app.mVDataMatrix(:,3),"mean","ThresholdFactor",3);
            Outlier3Channel4 = rmoutliers(app.mVDataMatrix(:,4),"mean","ThresholdFactor",3);
            Outlier3Channel5 = rmoutliers(app.mVDataMatrix(:,5),"mean","ThresholdFactor",3);
            
            ctr1 = (1:1:size(Outlier1Channel1,1));
            ctr2 = (1:1:size(Outlier1Channel2,1));
            ctr3 = (1:1:size(Outlier1Channel3,1));
            ctr4 = (1:1:size(Outlier1Channel4,1));
            ctr5 = (1:1:size(Outlier1Channel5,1));
            
            ctr6 = (1:1:size(Outlier2Channel1,1));
            ctr7 = (1:1:size(Outlier2Channel2,1));
            ctr8 = (1:1:size(Outlier2Channel3,1));
            ctr9 = (1:1:size(Outlier2Channel4,1));
            ctr10 = (1:1:size(Outlier2Channel5,1));
            
            ctr11 = (1:1:size(Outlier3Channel1,1));
            ctr12 = (1:1:size(Outlier3Channel2,1));
            ctr13 = (1:1:size(Outlier3Channel3,1));
            ctr14 = (1:1:size(Outlier3Channel4,1));
            ctr15 = (1:1:size(Outlier3Channel5,1));

            disp(app.dropDownVal);

            if app.OutlierOptionDropDown.Value == "1"                 
                 plot(app.OutlierDataChannel1,ctr1,Outlier1Channel1);
                 plot(app.OutlierDataChannel2,ctr2,Outlier1Channel2);
                 plot(app.OutlierDataChannel3,ctr3,Outlier1Channel3);
                 plot(app.OutlierDataChannel4,ctr4,Outlier1Channel4);
                 plot(app.OutlierDataChannel5,ctr5,Outlier1Channel5);
            elseif app.OutlierOptionDropDown.Value == "2"                 
                 plot(app.OutlierDataChannel1,ctr6,Outlier2Channel1);
                 plot(app.OutlierDataChannel2,ctr7,Outlier2Channel2);
                 plot(app.OutlierDataChannel3,ctr8,Outlier2Channel3);
                 plot(app.OutlierDataChannel4,ctr9,Outlier2Channel4);
                 plot(app.OutlierDataChannel5,ctr10,Outlier2Channel5);
            elseif app.OutlierOptionDropDown.Value == "3"                     
                 plot(app.OutlierDataChannel1,ctr11,Outlier3Channel1);
                 plot(app.OutlierDataChannel2,ctr12,Outlier3Channel2);
                 plot(app.OutlierDataChannel3,ctr13,Outlier3Channel3);
                 plot(app.OutlierDataChannel4,ctr14,Outlier3Channel4);
                 plot(app.OutlierDataChannel5,ctr15,Outlier3Channel5);
            else
                disp("value not found...");
            end
            
            if (height(app.SettingsUsed) == 3)
                app.SettingsUsed = [app.SettingsUsed; ("Removed outliers using " + app.OutlierOptionDropDown.Value + "standard deviation method")];
            end
            
            app.FilterOrderDropdown.Enable = 'on';
            app.FilterTypeDropdown.Enable = 'on';
            app.PROCESSINGTRACKAButton.Enable = 'on';
            app.TrackA_FC1.Enable = 'on';

        end

        % Value changed function: OutlierOptionDropDown
        function OutlierOptionDropDownValueChanged(app, event)
            app.dropDownVal = app.OutlierOptionDropDown.Value;
        end

        % Button pushed function: PROCESSINGTRACKBButton
        function PROCESSINGTRACKBButtonPushed(app, event)
            Fs = 100;
            Framelength = 10;
            %ctr1 = (1:1:size(app.rawChannelData,1));
            ch1 = app.rawChannelData(:,1); 
            movrmsWin = dsp.MovingRMS(20);
            movrmsWin_overlap = dsp.MovingRMS(20,15);
            movrmsExp = dsp.MovingRMS('Method','Exponential weighting',...
            'ForgettingFactor',0.995);

            scope  = timescope('SampleRate',[Fs,Fs,Fs/(20-15),Fs],...
                'TimeSpanOverrunAction','Scroll',...
                'TimeSpanSource','Property',...
                'TimeSpan',500,...
                'ShowGrid',true,...
                'YLimits',[-10.0 10]);
            title = 'Moving RMS';
            scope.Title = title;

                for i = 1:size(ch1,1)
                    x = 1 + 0.1 * randn(Framelength,1);
                    y1 = movrmsWin(ch1(i,1));
                    y2 = movrmsWin_overlap(ch1(i,1));
                    y3 = movrmsExp(ch1(i,1));
                    scope(x,y1,y2,y3);
                end
                show(scope);

        end

        % Button pushed function: PROCESSINGTRACKAButton
        function PROCESSINGTRACKAButtonPushed(app, event)


%             - CUTOFF FREQUENCY DROPDOWN MAKEN
%             - REKENING HOUDEN DAT LP EN HP FC SCALAR IS EN DEREST EEN 2D ARRAY

            % CLEAR ALLE PLOTS
            cla(app.TrackAchannel1);
            cla(app.TrackAchannel2);
            cla(app.TrackAchannel3); 
            cla(app.TrackAchannel4); 
            cla(app.TrackAchannel5); 

            channel1MvData = app.mVDataMatrix(:,1);
            channel2MvData = app.mVDataMatrix(:,2);
            channel3MvData = app.mVDataMatrix(:,3);
            channel4MvData = app.mVDataMatrix(:,4);
            channel5MvData = app.mVDataMatrix(:,5);


            if(isequal(num2str(app.FilterType),'hp') || isequal(num2str(app.FilterType),'lp'))
                    ylp =  filter1(num2str(app.FilterType),channel1MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value,'order', uint16(app.FilterOrder));
                    ylp2 = filter1(num2str(app.FilterType),channel2MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value,'order',uint16(app.FilterOrder));
                    ylp3 = filter1(num2str(app.FilterType),channel3MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value,'order',uint16(app.FilterOrder));
                    ylp4 = filter1(num2str(app.FilterType),channel4MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value,'order',uint16(app.FilterOrder));
                    ylp5 = filter1(num2str(app.FilterType),channel5MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value,'order',uint16(app.FilterOrder));
            elseif(isequal(num2str(app.FilterType),'bp') || isequal(num2str(app.FilterType),'bs'))
                    ylp =  filter1(num2str(app.FilterType),channel1MvData,'x',app.nSeq,'fc', [0.1 0.4],'order', uint16(app.FilterOrder));
                    ylp2 = filter1(num2str(app.FilterType),channel2MvData,'x',app.nSeq,'fc', [0.1 0.4],'order',uint16(app.FilterOrder));
                    ylp3 = filter1(num2str(app.FilterType),channel3MvData,'x',app.nSeq,'fc', [0.1 0.4],'order',uint16(app.FilterOrder));
                    ylp4 = filter1(num2str(app.FilterType),channel4MvData,'x',app.nSeq,'fc', [0.1 0.4],'order',uint16(app.FilterOrder));
                    ylp5 = filter1(num2str(app.FilterType),channel5MvData,'x',app.nSeq,'fc', [0.1 0.4],'order',uint16(app.FilterOrder));
            end

            hold(app.TrackAchannel1,'on');
            plot(app.TrackAchannel1, channel1MvData);
            plot(app.TrackAchannel1, ylp,'r');
            hold(app.TrackAchannel1,'off');

            hold(app.TrackAchannel2,'on');
            plot(app.TrackAchannel2, channel2MvData);
            plot(app.TrackAchannel2, ylp2,'r');
            hold(app.TrackAchannel2,'off');
           
            hold(app.TrackAchannel3,'on');
            plot(app.TrackAchannel3, channel3MvData);
            plot(app.TrackAchannel3, ylp3,'r');
            hold(app.TrackAchannel3,'off');

            hold(app.TrackAchannel4,'on');
            plot(app.TrackAchannel4, channel4MvData);
            plot(app.TrackAchannel4, ylp4,'r');
            hold(app.TrackAchannel4,'off');

            hold(app.TrackAchannel5,'on');
            plot(app.TrackAchannel5, channel5MvData);
            plot(app.TrackAchannel5, ylp5,'r');
            hold(app.TrackAchannel5,'off');

            if (height(app.SettingsUsed) == 4)
                app.SettingsUsed = [app.SettingsUsed; "Processing track A:"; ("\tFilter type: " + app.FilterType); ("\tFilter order: " + app.FilterOrder) ];
            end

        end

        % Value changed function: FilterTypeDropdown
        function FilterTypeDropdownValueChanged(app, event)
            value = app.FilterTypeDropdown.Value;
            if value == "High-pass"
                app.TrackA_FC2.Enable = 'off';
                app.TrackA_FC1.Limits = [0 1];
                app.FilterType = 'hp';
            elseif value == "Low-pass"
                app.TrackA_FC2.Enable = 'off';
                app.TrackA_FC1.Limits = [0 1];
                app.FilterType = 'lp';
            elseif value == "Band-pass"
                app.TrackA_FC2.Enable = 'on';
                app.TrackA_FC1.Value = 0;
                app.TrackA_FC2.Value = 1;
                app.TrackA_FC1.Limits = [0 round(app.TrackA_FC2.Value)];
                app.TrackA_FC2.Limits = [round(app.TrackA_FC1.Value) 100];
                app.FilterType = 'bp';
            elseif value == "Bandstop"
                app.TrackA_FC2.Enable = 'on';
                app.TrackA_FC1.Value = 0;
                app.TrackA_FC2.Value = 1;
                app.TrackA_FC1.Limits = [0 round(app.TrackA_FC2.Value)];
                app.TrackA_FC2.Limits = [round(app.TrackA_FC1.Value) 100];
                app.FilterType = 'bs';
            end

        end

        % Value changed function: FilterOrderDropdown
        function FilterOrderDropdownValueChanged(app, event)
            value = app.FilterOrderDropdown.Value;
            if value == "1"
                app.FilterOrder = 1;
            elseif value == "2"
                app.FilterOrder = 2;
            elseif value == "3"
                app.FilterOrder = 3;
            elseif value == "4"
                app.FilterOrder = 4;
            elseif value == "5"
                app.FilterOrder = 5;
            end
            
        end

        % Value changed function: TrackA_FC2
        function TrackA_FC2ValueChanged(app, event)
            value = app.TrackA_FC2.Value;
            
        end

        % Value changed function: TrackA_FC1
        function TrackA_FC1ValueChanged(app, event)
            value = app.TrackA_FC1.Value;    
        end

        % Button pushed function: EXPORTDATABEFOREMVCButton
        function EXPORTDATABEFOREMVCButtonPushed(app, event)
            
            % CURRENTLY MV DATA.............             
            
            header = ["CH1" "CH2" "CH3" "CH4" "CH5"];
            export = [header; app.mVDataMatrix];
            
            writematrix(export,'export.txt','Delimiter','\t');
            type export.txt;
            
            %second file for steps and settings
            writematrix(app.SettingsUsed,'settings.txt','Delimiter','\t') ; 
            type settings.txt;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.READTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.READTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.READTXTFILEButton.Text = 'READ TXT FILE';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'nSeq'; 'DI'; 'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable_2.RowName = {};
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.TEXTFILEDATALabel = uilabel(app.UIFigure);
            app.TEXTFILEDATALabel.Text = 'TEXT  FILE DATA';
            app.mVDATALabel = uilabel(app.UIFigure);
            app.mVDATALabel.Text = 'mV DATA';
            app.TOmVButton = uibutton(app.UIFigure, 'push');
            app.TOmVButton.ButtonPushedFcn = createCallbackFcn(app, @TOmVButtonPushed, true);
            app.TOmVButton.Enable = 'off';
            app.TOmVButton.Text = 'TO mV';
            app.FFTButton = uibutton(app.UIFigure, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Enable = 'off';
            app.FFTButton.Text = 'FFT';
            app.REMOVEOUTLIERButton = uibutton(app.UIFigure, 'push');
            app.REMOVEOUTLIERButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEOUTLIERButtonPushed, true);
            app.REMOVEOUTLIERButton.Enable = 'off';
            app.REMOVEOUTLIERButton.Text = 'REMOVE OUTLIER';
            app.OutlierOptionDropDown = uidropdown(app.UIFigure);
            app.OutlierOptionDropDown.Items = {'1', '2', '3'};
            app.OutlierOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OutlierOptionDropDownValueChanged, true);
            app.OutlierOptionDropDown.Enable = 'off';
            app.OutlierOptionDropDown.Value = '1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RAWDATATab = uitab(app.TabGroup);
            app.RAWDATATab.Title = 'RAW DATA';
            app.TabGroup2 = uitabgroup(app.RAWDATATab);
            app.CH1Tab = uitab(app.TabGroup2);
            app.CH1Tab.Title = 'CH1';
            app.RawDataChannel1 = uiaxes(app.CH1Tab);
            title(app.RawDataChannel1, 'CHANNEL 1')
            xlabel(app.RawDataChannel1, 'X')
            ylabel(app.RawDataChannel1, 'Y')
            zlabel(app.RawDataChannel1, 'Z')
            app.CH2Tab = uitab(app.TabGroup2);
            app.CH2Tab.Title = 'CH2';
            app.RawDataChannel2 = uiaxes(app.CH2Tab);
            title(app.RawDataChannel2, 'CHANNEL 2')
            xlabel(app.RawDataChannel2, 'X')
            ylabel(app.RawDataChannel2, 'Y')
            zlabel(app.RawDataChannel2, 'Z')
            app.CH3Tab = uitab(app.TabGroup2);
            app.CH3Tab.Title = 'CH3';
            app.RawDataChannel3 = uiaxes(app.CH3Tab);
            title(app.RawDataChannel3, 'CHANNEL 3')
            xlabel(app.RawDataChannel3, 'X')
            ylabel(app.RawDataChannel3, 'Y')
            zlabel(app.RawDataChannel3, 'Z')
            app.CH4Tab = uitab(app.TabGroup2);
            app.CH4Tab.Title = 'CH4';
            app.RawDataChannel4 = uiaxes(app.CH4Tab);
            title(app.RawDataChannel4, 'CHANNEL 4')
            xlabel(app.RawDataChannel4, 'X')
            ylabel(app.RawDataChannel4, 'Y')
            zlabel(app.RawDataChannel4, 'Z')
            app.CH5Tab = uitab(app.TabGroup2);
            app.CH5Tab.Title = 'CH5';
            app.RawDataChannel5 = uiaxes(app.CH5Tab);
            title(app.RawDataChannel5, 'CHANNEL 5')
            xlabel(app.RawDataChannel5, 'X')
            ylabel(app.RawDataChannel5, 'Y')
            zlabel(app.RawDataChannel5, 'Z')
            app.mVDATATab = uitab(app.TabGroup);
            app.mVDATATab.Title = 'mV DATA';
            app.TabGroup2_2 = uitabgroup(app.mVDATATab);
            app.CH1Tab_2 = uitab(app.TabGroup2_2);
            app.CH1Tab_2.Title = 'CH1';
            app.MvDataChannel1 = uiaxes(app.CH1Tab_2);
            title(app.MvDataChannel1, 'Title')
            xlabel(app.MvDataChannel1, 'X')
            ylabel(app.MvDataChannel1, 'Y')
            zlabel(app.MvDataChannel1, 'Z')
            app.CH2Tab_2 = uitab(app.TabGroup2_2);
            app.CH2Tab_2.Title = 'CH2';
            app.MvDataChannel2 = uiaxes(app.CH2Tab_2);
            title(app.MvDataChannel2, 'Title')
            xlabel(app.MvDataChannel2, 'X')
            ylabel(app.MvDataChannel2, 'Y')
            zlabel(app.MvDataChannel2, 'Z')
            app.CH3Tab_2 = uitab(app.TabGroup2_2);
            app.CH3Tab_2.Title = 'CH3';
            app.MvDataChannel3 = uiaxes(app.CH3Tab_2);
            title(app.MvDataChannel3, 'Title')
            xlabel(app.MvDataChannel3, 'X')
            ylabel(app.MvDataChannel3, 'Y')
            zlabel(app.MvDataChannel3, 'Z')
            app.CH4Tab_2 = uitab(app.TabGroup2_2);
            app.CH4Tab_2.Title = 'CH4';
            app.MvDataChannel4 = uiaxes(app.CH4Tab_2);
            title(app.MvDataChannel4, 'Title')
            xlabel(app.MvDataChannel4, 'X')
            ylabel(app.MvDataChannel4, 'Y')
            zlabel(app.MvDataChannel4, 'Z')
            app.CH5Tab_2 = uitab(app.TabGroup2_2);
            app.CH5Tab_2.Title = 'CH5';
            app.MvDataChannel5 = uiaxes(app.CH5Tab_2);
            title(app.MvDataChannel5, 'Title')
            xlabel(app.MvDataChannel5, 'X')
            ylabel(app.MvDataChannel5, 'Y')
            zlabel(app.MvDataChannel5, 'Z')
            app.FFTANALYSISTab = uitab(app.TabGroup);
            app.FFTANALYSISTab.Title = 'FFT ANALYSIS';
            app.TabGroup2_3 = uitabgroup(app.FFTANALYSISTab);
            app.CH1Tab_3 = uitab(app.TabGroup2_3);
            app.CH1Tab_3.Title = 'CH1';
            app.FFTDataChannel1 = uiaxes(app.CH1Tab_3);
            title(app.FFTDataChannel1, 'Title')
            xlabel(app.FFTDataChannel1, 'X')
            ylabel(app.FFTDataChannel1, 'Y')
            zlabel(app.FFTDataChannel1, 'Z')
            app.CH2Tab_3 = uitab(app.TabGroup2_3);
            app.CH2Tab_3.Title = 'CH2';
            app.FFTDataChannel2 = uiaxes(app.CH2Tab_3);
            title(app.FFTDataChannel2, 'Title')
            xlabel(app.FFTDataChannel2, 'X')
            ylabel(app.FFTDataChannel2, 'Y')
            zlabel(app.FFTDataChannel2, 'Z')
            app.CH3Tab_3 = uitab(app.TabGroup2_3);
            app.CH3Tab_3.Title = 'CH3';
            app.FFTDataChannel3 = uiaxes(app.CH3Tab_3);
            title(app.FFTDataChannel3, 'Title')
            xlabel(app.FFTDataChannel3, 'X')
            ylabel(app.FFTDataChannel3, 'Y')
            zlabel(app.FFTDataChannel3, 'Z')
            app.CH4Tab_3 = uitab(app.TabGroup2_3);
            app.CH4Tab_3.Title = 'CH4';
            app.FFTDataChannel4 = uiaxes(app.CH4Tab_3);
            title(app.FFTDataChannel4, 'Title')
            xlabel(app.FFTDataChannel4, 'X')
            ylabel(app.FFTDataChannel4, 'Y')
            zlabel(app.FFTDataChannel4, 'Z')
            app.CH5Tab_3 = uitab(app.TabGroup2_3);
            app.CH5Tab_3.Title = 'CH5';
            app.FFTDataChannel5 = uiaxes(app.CH5Tab_3);
            title(app.FFTDataChannel5, 'Title')
            xlabel(app.FFTDataChannel5, 'X')
            ylabel(app.FFTDataChannel5, 'Y')
            zlabel(app.FFTDataChannel5, 'Z')
            app.REMOVEOUTLIERTab = uitab(app.TabGroup);
            app.REMOVEOUTLIERTab.Title = 'REMOVE OUTLIER';
            app.TabGroup2_4 = uitabgroup(app.REMOVEOUTLIERTab);
            app.CH1Tab_4 = uitab(app.TabGroup2_4);
            app.CH1Tab_4.Title = 'CH1';
            app.OutlierDataChannel1 = uiaxes(app.CH1Tab_4);
            title(app.OutlierDataChannel1, 'Title')
            xlabel(app.OutlierDataChannel1, 'X')
            ylabel(app.OutlierDataChannel1, 'Y')
            zlabel(app.OutlierDataChannel1, 'Z')
            app.CH2Tab_4 = uitab(app.TabGroup2_4);
            app.CH2Tab_4.Title = 'CH2';
            app.OutlierDataChannel2 = uiaxes(app.CH2Tab_4);
            title(app.OutlierDataChannel2, 'Title')
            xlabel(app.OutlierDataChannel2, 'X')
            ylabel(app.OutlierDataChannel2, 'Y')
            zlabel(app.OutlierDataChannel2, 'Z')
            app.CH3Tab_4 = uitab(app.TabGroup2_4);
            app.CH3Tab_4.Title = 'CH3';
            app.OutlierDataChannel3 = uiaxes(app.CH3Tab_4);
            title(app.OutlierDataChannel3, 'Title')
            xlabel(app.OutlierDataChannel3, 'X')
            ylabel(app.OutlierDataChannel3, 'Y')
            zlabel(app.OutlierDataChannel3, 'Z')
            app.CH4Tab_4 = uitab(app.TabGroup2_4);
            app.CH4Tab_4.Title = 'CH4';
            app.OutlierDataChannel4 = uiaxes(app.CH4Tab_4);
            title(app.OutlierDataChannel4, 'Title')
            xlabel(app.OutlierDataChannel4, 'X')
            ylabel(app.OutlierDataChannel4, 'Y')
            zlabel(app.OutlierDataChannel4, 'Z')
            app.CH5Tab_4 = uitab(app.TabGroup2_4);
            app.CH5Tab_4.Title = 'CH5';
            app.OutlierDataChannel5 = uiaxes(app.CH5Tab_4);
            title(app.OutlierDataChannel5, 'Title')
            xlabel(app.OutlierDataChannel5, 'X')
            ylabel(app.OutlierDataChannel5, 'Y')
            zlabel(app.OutlierDataChannel5, 'Z')
            app.PROCESSINGTRACKATab = uitab(app.TabGroup);
            app.PROCESSINGTRACKATab.Title = 'PROCESSING TRACK A';
            app.TabGroup2_5 = uitabgroup(app.PROCESSINGTRACKATab);
            app.CH1Tab_5 = uitab(app.TabGroup2_5);
            app.CH1Tab_5.Title = 'CH1';
            app.TrackAchannel1 = uiaxes(app.CH1Tab_5);
            title(app.TrackAchannel1, 'Title')
            xlabel(app.TrackAchannel1, 'X')
            ylabel(app.TrackAchannel1, 'Y')
            zlabel(app.TrackAchannel1, 'Z')
            app.CH2Tab_5 = uitab(app.TabGroup2_5);
            app.CH2Tab_5.Title = 'CH2';
            app.TrackAchannel2 = uiaxes(app.CH2Tab_5);
            title(app.TrackAchannel2, 'Title')
            xlabel(app.TrackAchannel2, 'X')
            ylabel(app.TrackAchannel2, 'Y')
            zlabel(app.TrackAchannel2, 'Z')
            app.CH3Tab_5 = uitab(app.TabGroup2_5);
            app.CH3Tab_5.Title = 'CH3';
            app.TrackAchannel3 = uiaxes(app.CH3Tab_5);
            title(app.TrackAchannel3, 'Title')
            xlabel(app.TrackAchannel3, 'X')
            ylabel(app.TrackAchannel3, 'Y')
            zlabel(app.TrackAchannel3, 'Z')
            app.CH4Tab_5 = uitab(app.TabGroup2_5);
            app.CH4Tab_5.Title = 'CH4';
            app.TrackAchannel4 = uiaxes(app.CH4Tab_5);
            title(app.TrackAchannel4, 'Title')
            xlabel(app.TrackAchannel4, 'X')
            ylabel(app.TrackAchannel4, 'Y')
            zlabel(app.TrackAchannel4, 'Z')
            app.CH5Tab_5 = uitab(app.TabGroup2_5);
            app.CH5Tab_5.Title = 'CH5';
            app.TrackAchannel5 = uiaxes(app.CH5Tab_5);
            title(app.TrackAchannel5, 'Title')
            xlabel(app.TrackAchannel5, 'X')
            ylabel(app.TrackAchannel5, 'Y')
            zlabel(app.TrackAchannel5, 'Z')
            app.PROCESSINGTRACKBTab = uitab(app.TabGroup);
            app.PROCESSINGTRACKBTab.Title = 'PROCESSING TRACK B';
            app.MVCNORMALISATIONTab = uitab(app.TabGroup);
            app.MVCNORMALISATIONTab.Title = 'MVC NORMALISATION';
            app.PROCESSINGTRACKAButton = uibutton(app.UIFigure, 'push');
            app.PROCESSINGTRACKAButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSINGTRACKAButtonPushed, true);
            app.PROCESSINGTRACKAButton.Enable = 'off';
            app.PROCESSINGTRACKAButton.Text = 'PROCESSING TRACK A';
            app.PROCESSINGTRACKBButton = uibutton(app.UIFigure, 'push');
            app.PROCESSINGTRACKBButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSINGTRACKBButtonPushed, true);
            app.PROCESSINGTRACKBButton.Enable = 'off';
            app.PROCESSINGTRACKBButton.Text = 'PROCESSING TRACK B';
            app.MVCNORMALISATIONButton = uibutton(app.UIFigure, 'push');
            app.MVCNORMALISATIONButton.Enable = 'off';
            app.MVCNORMALISATIONButton.Text = 'MVC NORMALISATION';
            app.EXPORTDATAAFTERMVCButton = uibutton(app.UIFigure, 'push');
            app.EXPORTDATAAFTERMVCButton.Enable = 'off';
            app.EXPORTDATAAFTERMVCButton.Text = 'EXPORT DATA AFTER MVC';
            app.FilterTypeDropdown = uidropdown(app.UIFigure);
            app.FilterTypeDropdown.Items = {'High-pass', 'Low-pass', 'Band-pass', 'Bandstop'};
            app.FilterTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropdownValueChanged, true);
            app.FilterTypeDropdown.Enable = 'off';
            app.FilterTypeDropdown.Placeholder = 'Select filter';
            app.FilterTypeDropdown.Value = 'High-pass';
            app.FilterOrderDropdown = uidropdown(app.UIFigure);
            app.FilterOrderDropdown.Items = {'1', '2', '3', '4', '5'};
            app.FilterOrderDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterOrderDropdownValueChanged, true);
            app.FilterOrderDropdown.Enable = 'off';
            app.FilterOrderDropdown.Value = '1';
            app.TrackA_FC2 = uieditfield(app.UIFigure, 'numeric');
            app.TrackA_FC2.Limits = [0 1];
            app.TrackA_FC2.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC2ValueChanged, true);
            app.TrackA_FC2.Enable = 'off';
            app.TrackA_FC1 = uieditfield(app.UIFigure, 'numeric');
            app.TrackA_FC1.Limits = [0 1];
            app.TrackA_FC1.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC1ValueChanged, true);
            app.TrackA_FC1.Enable = 'off';
            app.EXPORTDATABEFOREMVCButton = uibutton(app.UIFigure, 'push');
            app.EXPORTDATABEFOREMVCButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTDATABEFOREMVCButtonPushed, true);
            app.EXPORTDATABEFOREMVCButton.Text = 'EXPORT DATA BEFORE MVC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end